classdef app_password < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        UITable     matlab.ui.control.Table
        EditField   matlab.ui.control.EditField
        HTML        matlab.ui.control.HTML
    end

    
    properties (Access = private)
        sPass 
        sFail
        constraintNumberCharacters
        constraintNumeric
        constraintUpperCase
    end
    
    methods (Access = private)
        
        function displayPasswordAndCheckConstraints(app, ~, ~)
            app.EditField.Value = app.HTML.Data;
            app.checkConstraints();
        end

        function checkConstraints(app)
            password                        = app.HTML.Data;
            app.constraintNumberCharacters  = strlength(password) >= 8;
            app.constraintNumeric           = ~isempty(extract(password, digitsPattern));
            app.constraintUpperCase         = any(isstrprop(password,'upper'));
            if app.constraintNumberCharacters
                addStyle(app.UITable, app.sPass,"cell",[1 1]);
            else
                addStyle(app.UITable, app.sFail,"cell",[1 1]);
            end
            if app.constraintNumeric
                addStyle(app.UITable, app.sPass,"cell",[2 1]);
            else
                addStyle(app.UITable, app.sFail,"cell",[2 1]);
            end
            if app.constraintUpperCase
                addStyle(app.UITable, app.sPass,"cell",[3 1]);
            else
                addStyle(app.UITable, app.sFail,"cell",[3 1]);
            end            

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.HTML.HTMLSource     = 'passwordExample.html';
            app.HTML.DataChangedFcn = @(src,event)app.displayPasswordAndCheckConstraints();
            app.UITable.Data        = ["At least 8 characters"; "At least one number"; "At least one upper case"];
            app.sPass               = uistyle("Icon","success","IconAlignment","rightmargin");
            app.sFail               = uistyle("Icon","error","IconAlignment","rightmargin");
            addStyle(app.UITable, app.sFail,"cell",[1 1;2 1;3 1])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.HTML = uihtml(app.GridLayout);
            app.EditField = uieditfield(app.GridLayout, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'password';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_password

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end